classdef SimpleApp < matlab.apps.AppBase
    %SIMPLEAPP  Tiny App Designer fixture used by the autotest self-tests.
    %
    %   This is intentionally minimal:  one figure, one button, one
    %   callback.  It exercises the .mlapp -> SourceModel -> generated
    %   test class pipeline without depending on any App Designer
    %   features beyond a UIFigure and a Button.
    %
    %   Example:
    %       app = SimpleApp;
    %       delete(app);

    properties (Access = public)
        UIFigure  matlab.ui.Figure
        MyButton  matlab.ui.control.Button
    end

    properties (Access = private)
        PressCount (1,1) double = 0
    end

    methods (Access = private)
        function startupFcn(app)
            % Initialise anything needed at startup.
            app.PressCount = 0;
        end

        function MyButtonPushed(app, event)
            % Increment a counter and update the button label.
            app.PressCount = app.PressCount + 1;
            app.MyButton.Text = sprintf('Pressed %d', app.PressCount);
        end
    end

    methods (Access = public)
        function app = SimpleApp()
            % Construct the UI off-screen so tests can launch headlessly.
            app.UIFigure = uifigure('Visible', 'off');
            app.MyButton = uibutton(app.UIFigure, ...
                'Position', [10 10 100 22], ...
                'Text', 'Press', ...
                'Tag', 'MyButton');
        end

        function delete(app)
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                delete(app.UIFigure);
            end
        end
    end
end